classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GenerujButton            matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        TypzwarciaDropDown       matlab.ui.control.DropDown
        TypzwarciaDropDownLabel  matlab.ui.control.Label
        UkadDropDown             matlab.ui.control.DropDown
        UkadDropDownLabel        matlab.ui.control.Label
        ImportButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)

        % Tutaj umieść kod obsługi przycisku "Import"
        % Możesz skorzystać z przykładu z wcześniejszej odpowiedzi, aby otworzyć plik CSV i przetworzyć dane.
        % Na przykład:
        [filename, pathname] = uigetfile({'*.csv', 'Pliki CSV (*.csv)'; '*.txt', 'Pliki tekstowe (*.txt)'}, 'Wybierz plik CSV');
        if ischar(filename) && ischar(pathname)
            % Wybrane plik zostanie otwarty i przetworzony
            fullFileName = fullfile(pathname, filename);
            % Tutaj możesz dodać kod do obsługi wybranego pliku CSV
        end
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.UkadDropDownLabel = uilabel(app.UIFigure);
            app.UkadDropDownLabel.Text = 'Układ';
            app.UkadDropDown = uidropdown(app.UIFigure);
            app.UkadDropDown.Items = {'Gwiazda pełna', 'Gwiazda niepełna', 'Trójkąt', 'Holmgreen', 'Krzyżowy'};
            app.UkadDropDown.Value = 'Gwiazda pełna';
            app.TypzwarciaDropDownLabel = uilabel(app.UIFigure);
            app.TypzwarciaDropDownLabel.Text = 'Typ zwarcia';
            app.TypzwarciaDropDown = uidropdown(app.UIFigure);
            app.TypzwarciaDropDown.Items = {'L1-N', 'L2-N', 'L3-N', 'L1-L2-N', 'L1-L3-N', 'L2-L3-N', 'L1-L2', 'L1-L3', 'L2-L3', 'L1-L2-L3'};
            app.TypzwarciaDropDown.Value = 'L1-N';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'I_PL1'; 'I_PL2'; 'I_PL3'; 'I_N'; 'I_SL1'; 'I_SL2'; 'I_SL3'; 'I_SL0'; 'I_SL1-L2'; 'I_SL2-L3'; 'I_SL1-L3'};
            app.UITable.RowName = {};
            app.GenerujButton = uibutton(app.UIFigure, 'push');
            app.GenerujButton.Text = 'Generuj';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end