classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        TypzwarciaDropDown       matlab.ui.control.DropDown
        TypzwarciaDropDownLabel  matlab.ui.control.Label
        UkadDropDown             matlab.ui.control.DropDown
        UkadDropDownLabel        matlab.ui.control.Label
        GenerujButton            matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        ImportButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
              % Tutaj umieść kod obsługi przycisku "Import"
        % Możesz skorzystać z przykładu z wcześniejszej odpowiedzi, aby otworzyć plik CSV i przetworzyć dane.
        % Na przykład:
        [filename, pathname] = uigetfile({'*.csv', 'Pliki CSV (*.csv)'; '*.txt', 'Pliki tekstowe (*.txt)'}, 'Wybierz plik CSV');
        if ischar(filename) && ischar(pathname)
            % Wybrane plik zostanie otwarty i przetworzony
            fullFileName = fullfile(pathname, filename);
            % Tutaj możesz dodać kod do obsługi wybranego pliku CSV
            % Wybrane plik zostanie otwarty i przetworzony
     
        % Wczytaj dane z pliku CSV (przykładowo używając funkcji csvread lub readtable)
        % Przykładowy kod załadowania danych (w zależności od formatu pliku CSV):
 data = readtable(fullFileName); % Jeśli używasz tabeli
%         data = csvread(fullFileName); % Jeśli używasz macierzy
        display(data);
        % Zaktualizuj dane w UITable
        app.UITable.Data = data; % Aktualizuj dane w UITable
        app.UITable.ColumnName = data.Properties.VariableNames; % Przypisz nazwy kolumn
        app.UITable.RowName = {}; % Wyczyść nazwy wierszy
        end
        end

        % Button pushed function: GenerujButton
        function GenerujButtonPushed(app, event)
            % Odczytaj wybrany układ z listy rozwijalnej
    
    wybranyZwarcie = app.TypzwarciaDropDown.Value;

    % Odczytaj dane z UITable
    data = app.UITable.Data;
      % Przypisz dane z każdego wiersza do odpowiednich zmiennych
    for i = 1:size(data, 1)
        daneWiersza{i} = data(i, :);
    end
wybranyUklad = app.UkadDropDown.Value;

switch wybranyUklad
    case 'Gwiazda pełna'
        % Kod dla układu "Gwiazda pełna"
        komunikat = sprintf('Gwiazda pełna');
        uialert(app.UIFigure, komunikat, 'dziad ufolud', 'Icon', 'info');
      % Odczytaj wybraną opcję z pola rozwijalnego
numerOpcji = str2double(app.TypzwarciaDropDown.Value);
komunikat = sprintf('Numer wybranej opcji: %d', numerOpcji);

uialert(app.UIFigure, komunikat, 'Dziad UFOlud', 'Icon', 'info');


wiersz = numerOpcji;

% Teraz 'numerOpcji' zawiera numer wybranej opcji (indeksowany od 1).

Ipl1 = data{wiersz, 2};
Ipl2 = data{wiersz, 3}* exp(1)^(deg2rad(-120) * 1i);
Ipl3 = data{wiersz, 4}* exp(1)^(deg2rad(120) * 1i);

Isl1 = -data{wiersz, 6};
Isl2 = -data{wiersz, 7}* exp(1)^(deg2rad(-120) * 1i);
Isl3 = -data{wiersz, 8}* exp(1)^(deg2rad(120) * 1i);
Ipn = data{wiersz, 5};
Is0 = data{wiersz, 9};
% Obliczenie kąta prądu neutralnego względem sumy prądów fazowych
angle_deg = rad2deg(angle(Ipn));
angle_deg2 = rad2deg(angle(Is0));
Ipn=data{wiersz,4}*exp(1)^(angle_deg*1i);
Is0 = -data{wiersz,9}*exp(1)^(angle_deg2*1i);
% Wyświetlenie kąta
disp(['Kąt prądu neutralnego Ipn: ', num2str(angle_deg), ' stopni']);
disp(['Kąt prądu neutralnego Ipn: ', num2str(angle_deg2), ' stopni']);
disp(['Ipl1: ' num2str(Ipl1)]);
disp(['Ipl2: ' num2str(Ipl2)]);
disp(['Ipl3: ' num2str(Ipl3)]);
disp(['Ipn: ' num2str(Ipn)]);
disp(['Isl1: ' num2str(Isl1)]);
disp(['Isl2: ' num2str(Isl2)]);
disp(['Isl3: ' num2str(Isl3)]);
disp(['Is0: ' num2str(Is0)]);
disp(['Ipl1: ' num2str(Ipl1) ', Ipl2: ' num2str(Ipl2) ', Ipl3: ' num2str(Ipl3) ', Ipn: ' num2str(Ipn) ', Isl1: ' num2str(Isl1) ', Isl2: ' num2str(Isl2) ', Isl3: ' num2str(Isl3) ', Is0: ' num2str(Is0)]);
% Przygotowanie danych do wykresu
Imag = [Ipl1,Ipl2,Ipl3,Isl1,Isl2,Isl3];
Re = real(Imag);
Im = imag(Imag);

% Tworzenie wektorów dla wykresu
figure;
hold on;
quiver(0, 0, Re(1), Im(1), 0, 'b', 'LineWidth', 1.5);
quiver(0, 0, Re(2), Im(2), 0, 'r', 'LineWidth', 1.5);
quiver(0, 0, Re(3), Im(3), 0, 'g', 'LineWidth', 1.5);
quiver(0, 0, Re(4), Im(4), 0, 'c', 'LineWidth', 1.5);
quiver(0, 0, Re(5), Im(5), 0, 'm', 'LineWidth', 1.5);
quiver(0, 0, Re(6), Im(6), 0, 'y', 'LineWidth', 1.5);
quiver(0, 0, real(Ipn), imag(Ipn), 0, 'k', 'LineWidth', 1.5);
quiver(0,0,real(Is0),imag(Is0),0,'k','LineWidth',1.5);
% Legenda
legend('Ipl1', 'Ipl2', 'Ipl3',' Isl1','Isl2','Isl3','In','Location', 'Best');
        % Konfiguracja osi
axis equal;
xlabel('Re');
ylabel('Im');
grid on;


    case 'Gwiazda niepełna'
        % Kod dla układu "Gwiazda niepełna"
        komunikat = sprintf('Gwiazda niepełna');
        uialert(app.UIFigure, komunikat, 'dziad ufolud', 'Icon', 'info');
        % Dodaj tu odpowiedni kod dla tego układu
        % Odczytaj wybraną opcję z pola rozwijalnego
numerOpcji = str2double(app.TypzwarciaDropDown.Value);
komunikat = sprintf('Numer wybranej opcji: %d', numerOpcji);

uialert(app.UIFigure, komunikat, 'Dziad UFOlud', 'Icon', 'info');


wiersz = numerOpcji;

% Teraz 'numerOpcji' zawiera numer wybranej opcji (indeksowany od 1).

Ipl1 = data{wiersz, 2};
Ipl2 = data{wiersz, 3}* exp(1)^(deg2rad(-120) * 1i);
Ipl3 = data{wiersz, 4}* exp(1)^(deg2rad(120) * 1i);
Isl1 = -data{wiersz, 6};

Isl3 = -data{wiersz, 7}* exp(1)^(deg2rad(120) * 1i);
Ipn = data{wiersz, 5};
Is0 = data{wiersz, 8};
% Obliczenie kąta prądu neutralnego względem sumy prądów fazowych
angle_deg = rad2deg(angle(Ipn));
angle_deg2 = rad2deg(angle(Is0));

Ipn=data{wiersz,4}*exp(1)^(angle_deg*1i);
Is0 = -data{wiersz,8}*exp(1)^(angle_deg2*1i);
% Wyświetlenie kąta
disp(['Kąt prądu neutralnego Ipn: ', num2str(angle_deg), ' stopni']);
disp(['Kąt prądu neutralnego Ipn: ', num2str(angle_deg2), ' stopni']);
disp(['Ipl1: ' num2str(Ipl1)]);
disp(['Ipl2: ' num2str(Ipl2)]);
disp(['Ipl3: ' num2str(Ipl3)]);
disp(['Ipn: ' num2str(Ipn)]);
disp(['Isl1: ' num2str(Isl1)]);

disp(['Isl3: ' num2str(Isl3)]);
disp(['Is0: ' num2str(Is0)]);
disp(['Ipl1: ' num2str(Ipl1) ', Ipl2: ' num2str(Ipl2) ', Ipl3: ' num2str(Ipl3) ', Ipn: ' num2str(Ipn) ', Isl1: ' num2str(Isl1)  ', Isl3: ' num2str(Isl3) ', Is0: ' num2str(Is0)]);
% Przygotowanie danych do wykresu
Imag = [Ipl1,Ipl2,Ipl3,Isl1,Isl3, Is0];
Re = real(Imag);
Im = imag(Imag);

% Tworzenie wektorów dla wykresu
figure;
hold on;
quiver(0, 0, Re(1), Im(1), 0, 'b', 'LineWidth', 1.5);
quiver(0, 0, Re(2), Im(2), 0, 'r', 'LineWidth', 1.5);
quiver(0, 0, Re(3), Im(3), 0, 'g', 'LineWidth', 1.5);
quiver(0, 0, Re(4), Im(4), 0, 'c', 'LineWidth', 1.5);
quiver(0, 0, Re(5), Im(5), 0, 'm', 'LineWidth', 1.5);
quiver(0, 0, Re(6), Im(6), 0, 'k', 'LineWidth', 1.5);
quiver(0, 0, real(Ipn), imag(Ipn), 0, 'k', 'LineWidth', 1.5);
% Legenda
legend('Ipl1', 'Ipl2', 'Ipl3',' Isl1','Isl2','Isl3','In','Location', 'Best');
        % Konfiguracja osi
axis equal;
xlabel('Re');
ylabel('Im');
grid on;
    case 'Trójkąt'
        % Kod dla układu "Trójkąt"
        komunikat = sprintf('Trójkąt');
        uialert(app.UIFigure, komunikat, 'dziad ufolud', 'Icon', 'info');
        % Dodaj tu odpowiedni kod dla tego układu
        % Odczytaj wybraną opcję z pola rozwijalnego
numerOpcji = str2double(app.TypzwarciaDropDown.Value);
komunikat = sprintf('Numer wybranej opcji: %d', numerOpcji);

uialert(app.UIFigure, komunikat, 'Dziad UFOlud', 'Icon', 'info');


wiersz = numerOpcji;

% Teraz 'numerOpcji' zawiera numer wybranej opcji (indeksowany od 1).

Ipl1 = data{wiersz, 2};
Ipl2 = data{wiersz, 3}* exp(1)^(deg2rad(-120) * 1i);
Ipl3 = data{wiersz, 4}* exp(1)^(deg2rad(120) * 1i);
Ipn = data{wiersz, 5};
Isl1 = -data{wiersz, 6};
Isl2 = -data{wiersz, 7}* exp(1)^(deg2rad(-120) * 1i);
Isl3 = -data{wiersz, 8}* exp(1)^(deg2rad(120) * 1i);
Isl1_sl2 = data{wiersz, 9};
Isl2_sl3 = data{wiersz, 10};
Isl1_sl3 = data{wiersz, 11};
% Obliczenie kąta prądu neutralnego względem sumy prądów fazowych
angle_deg = rad2deg(angle(Ipn));
angle_deg2 = rad2deg(angle(Isl1_sl2));
angle_deg3 = rad2deg(angle(Isl2_sl3));
angle_deg4 = rad2deg(angle(Isl1_sl3));
Ipn=data{wiersz,5}*exp(1)^(angle_deg*1i);
Isl1_sl2 = data{wiersz,9}*exp(1)^(angle_deg2*1i);
Isl2_sl3 = data{wiersz,10}*exp(1)^(angle_deg3*1i);
Isl1_sl3 = data{wiersz,11}*exp(1)^(angle_deg4*1i);

% Przygotowanie danych do wykresu
Imag = [Ipl1,Ipl2,Ipl3,Isl1,Isl2,Isl3,Isl1_sl2,Isl2_sl3,Isl1_sl3,Ipn];
Re = real(Imag);
Im = imag(Imag);

% Tworzenie wektorów dla wykresu
figure;
hold on;
quiver(0, 0, Re(1), Im(1), 0, 'b', 'LineWidth', 1.5);
quiver(0, 0, Re(2), Im(2), 0, 'r', 'LineWidth', 1.5);
quiver(0, 0, Re(3), Im(3), 0, 'g', 'LineWidth', 1.5);
quiver(0, 0, Re(4), Im(4), 0, 'c', 'LineWidth', 1.5);
quiver(0, 0, Re(5), Im(5), 0, 'm', 'LineWidth', 1.5);
quiver(0, 0, Re(6), Im(6), 0, 'y', 'LineWidth', 1.5);
quiver(0, 0, Re(7), Im(7), 0, 'y', 'LineWidth', 1.5);
quiver(0, 0, real(8), imag(8), 0, 'k', 'LineWidth', 1.5);
quiver(0, 0, real(9), imag(9), 0, 'k', 'LineWidth', 1.5);
quiver(0, 0, real(10), imag(10), 0, 'k', 'LineWidth', 1.5);
% Legenda
legend('Ipl1', 'Ipl2', 'Ipl3',' Isl1','Isl2','Isl3','Isl1_sl2','Isl2_sl3','Isl1_sl3','Ipn','Location', 'Best');
        % Konfiguracja osi
axis equal;
xlabel('Re');
ylabel('Im');
grid on;
    case 'Holmgreen'
        % Kod dla układu "Holmgreen"
        komunikat = sprintf('Holmgreen');
        uialert(app.UIFigure, komunikat, 'dziad ufolud', 'Icon', 'info');
        % Dodaj tu odpowiedni kod dla tego układu
        % Odczytaj wybraną opcję z pola rozwijalnego
numerOpcji = str2double(app.TypzwarciaDropDown.Value);
komunikat = sprintf('Numer wybranej opcji: %d', numerOpcji);

uialert(app.UIFigure, komunikat, 'Dziad UFOlud', 'Icon', 'info');


wiersz = numerOpcji;

% Teraz 'numerOpcji' zawiera numer wybranej opcji (indeksowany od 1).

Ipl1 = data{wiersz, 2};
Ipl2 = data{wiersz, 3}* exp(1)^(deg2rad(-120) * 1i);
Ipl3 = data{wiersz, 4}* exp(1)^(deg2rad(120) * 1i);
Ipn = data{wiersz, 5};

Is0 = -data{wiersz, 6};
% Obliczenie kąta prądu neutralnego względem sumy prądów fazowych
angle_deg = rad2deg(angle(Ipn));
angle_deg2 = rad2deg(angle(Is0));
Ipn = data{wiersz, 5}* exp(1)^(angle_deg * 1i);
Is0 = -data{wiersz, 6}* exp(1)^(angle_deg2 * 1i);
% Przygotowanie danych do wykresu
Imag = [Ipl1,Ipl2,Ipl3,Ipn,Is0];
Re = real(Imag);
Im = imag(Imag);

% Tworzenie wektorów dla wykresu
figure;
hold on;
quiver(0, 0, Re(1), Im(1), 0, 'b', 'LineWidth', 1.5);
quiver(0, 0, Re(2), Im(2), 0, 'r', 'LineWidth', 1.5);
quiver(0, 0, Re(3), Im(3), 0, 'g', 'LineWidth', 1.5);
quiver(0, 0, Re(4), Im(4), 0, 'c', 'LineWidth', 1.5);
quiver(0, 0, Re(5), Im(5), 0, 'm', 'LineWidth', 1.5);

% Legenda
legend('Ipl1', 'Ipl2', 'Ipl3',' Ipn','Is0','Location', 'Best');
        % Konfiguracja osi
axis equal;
xlabel('Re');
ylabel('Im');
grid on;
    case 'Krzyżowy'
        % Kod dla układu "Krzyżowy"
        komunikat = sprintf('Krzyżowy');
        uialert(app.UIFigure, komunikat, 'dziad ufolud', 'Icon', 'info');
        % Dodaj tu odpowiedni kod dla tego układu
        % Odczytaj wybraną opcję z pola rozwijalnego
numerOpcji = str2double(app.TypzwarciaDropDown.Value);
komunikat = sprintf('Numer wybranej opcji: %d', numerOpcji);

uialert(app.UIFigure, komunikat, 'Dziad UFOlud', 'Icon', 'info');


wiersz = numerOpcji;

% Teraz 'numerOpcji' zawiera numer wybranej opcji (indeksowany od 1).

Ipl1 = data{wiersz, 2};
Ipl2 = data{wiersz, 3}* exp(1)^(deg2rad(-120) * 1i);
Ipl3 = data{wiersz, 4}* exp(1)^(deg2rad(120) * 1i);
Ipn = data{wiersz, 5};
Isl3_l1 = -data{wiersz, 6};

% Obliczenie kąta prądu neutralnego względem sumy prądów fazowych
angle_deg = rad2deg(angle(Ipn));
angle_deg2 = rad2deg(angle(Isl3_l1));
Ipn = data{wiersz, 5}* exp(1)^(angle_deg * 1i);
Isl3_l1 = data{wiersz, 6}* exp(1)^(angle_deg2 * 1i);

% Przygotowanie danych do wykresu
Imag = [Ipl1,Ipl2,Ipl3,Ipn,Isl3_l1];
Re = real(Imag);
Im = imag(Imag);

% Tworzenie wektorów dla wykresu
figure;
hold on;
quiver(0, 0, Re(1), Im(1), 0, 'b', 'LineWidth', 1.5);
quiver(0, 0, Re(2), Im(2), 0, 'r', 'LineWidth', 1.5);
quiver(0, 0, Re(3), Im(3), 0, 'g', 'LineWidth', 1.5);
quiver(0, 0, Re(4), Im(4), 0, 'c', 'LineWidth', 1.5);
quiver(0, 0, Re(5), Im(5), 0, 'm', 'LineWidth', 1.5);

% Legenda
legend('Ipl1', 'Ipl2', 'Ipl3',' Ipn','Isl3_l1','Location', 'Best');
        % Konfiguracja osi
axis equal;
xlabel('Re');
ylabel('Im');
grid on;
    otherwise
        % Domyślny kod, który wykonuje się, jeśli nie pasuje do żadnej z powyższych opcji
       komunikat = sprintf('Nieznany układ');
        uialert(app.UIFigure, komunikat, 'dziad ufolud', 'Icon', 'info');
        
end

    % Teraz dane z każdego wiersza są przechowywane w komórkach 'daneWiersza'
    % Możesz dalej przetwarzać te dane w zależności od potrzeb.

    % Tutaj możesz użyć 'daneWiersza' do generowania wykresu lub do innych operacji.

    % Wyświetlenie komunikatu po naciśnięciu przycisku
    

    % Tutaj możesz utworzyć tytuł wykresu na podstawie wybranego układu
    tytulWykresu = ['Wykres dla układu ' wybranyUklad ' zwarcie ' wybranyZwarcie];
    
    % Ustaw tytuł wykresu
    title(app.UIAxes, tytulWykresu);

    % Tutaj możesz dalej generować i rysować wykres w zależności od wybranego układu
    % oraz aktualnych danych w UITable.
    % Możesz również dostosować inne właściwości wykresu w UIAxes, jak xlabel, ylabel itp.
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
              komunikat = sprintf(['Witaj w kreatorze wykresów wektorowych do ćwiczenia 1! Aby zaimportować dane utwórz plik .csv. ' ...
                  'Ważne:Aby program działał prawidłowo nazwy kolumn jak i dane powinny pasować. Polecam zobaczyć plik example.csv.' ...
                  'Jeżeli dane się załadowały prawidłowo, powinne się po kilku sekundach dane pojawić w tabeli oraz powinny się zmienić wartości..' ...
                  'Sepratorem liczb jest kropka a przecinek do wartości CSV.  ' ...
                  'Typy zwarć odpowiadają danym numerom:' ...
                  '1. L1-N ' ...
                  '2.L2-N' ...
                  '3.L3-N' ...
                  '4.L1-L2-N' ...
                  '5.L1-L3-N' ...
                  '6.L2-L3-N' ...
                  '7.L1-L2' ...
                  '8.L1-L3' ...
                  '9.L2-L3' ...
                  '10.L1-L2-L3']);
    uialert(app.UIFigure, komunikat, 'Jak używać', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'I_PL1'; 'I_PL2'; 'I_PL3'; 'I_N'; 'I_SL1'; 'I_SL2'; 'I_SL3'; 'I_SL0'; 'I_SL1-L2'; 'I_SL2-L3'; 'I_SL1-L3'};
            app.UITable.RowName = {};
            app.GenerujButton = uibutton(app.UIFigure, 'push');
            app.GenerujButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujButtonPushed, true);
            app.GenerujButton.Text = 'Generuj';
            app.UkadDropDownLabel = uilabel(app.UIFigure);
            app.UkadDropDownLabel.Text = 'Układ';
            app.UkadDropDown = uidropdown(app.UIFigure);
            app.UkadDropDown.Items = {'Gwiazda pełna', 'Gwiazda niepełna', 'Trójkąt', 'Holmgreen', 'Krzyżowy'};
            app.UkadDropDown.Value = 'Trójkąt';
            app.TypzwarciaDropDownLabel = uilabel(app.UIFigure);
            app.TypzwarciaDropDownLabel.Text = 'Typ zwarcia';
            app.TypzwarciaDropDown = uidropdown(app.UIFigure);
            app.TypzwarciaDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.TypzwarciaDropDown.Value = '1';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end